classdef distributions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ChooseyourdistributionDropDownLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        ChooseyourdistributionDropDown  matlab.ui.control.DropDown
    end

    methods (Access = private)

        % Value changed function: ChooseyourdistributionDropDown
        function ChooseyourdistributionDropDownValueChanged(app, event)
            value = app.ChooseyourdistributionDropDown.Value;
            x = [-10:.1:10];
            switch value
                case 'Standard Normal'
                    y = normpdf(x,0,1);
                    plot(app.UIAxes, x, y)
                case 'Normal'
                    y = normpdf(x,1,6);
                    plot(app.UIAxes, x, y)
                case 'T distribution'
                    y = tpdf(0,2)
                    plot(app.UIAxes, x, y)
                case 'Gamma distribution'
                    y = gampdf(x,9,1)
                    plot(app.UIAxes, x, y)
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ChooseyourdistributionDropDownLabel = uilabel(app.UIFigure);
            app.ChooseyourdistributionDropDownLabel.Text = 'Choose your distribution!';
            app.ChooseyourdistributionDropDown = uidropdown(app.UIFigure);
            app.ChooseyourdistributionDropDown.Items = {'Standard Normal', 'Normal', 'T distribution', 'Gamma distribution'};
            app.ChooseyourdistributionDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseyourdistributionDropDownValueChanged, true);
            app.ChooseyourdistributionDropDown.Value = 'Standard Normal';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Density function')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'p(x)')
            app.UIAxes.XTick = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.UIAxes.XTickLabel = {'-10'; '-9'; '-8'; '-7'; '-6'; '-5'; '-4'; '-3'; '-2'; '-1'; '0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
        end
    end

    methods (Access = public)

        % Construct app
        function app = distributions

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end